classdef ManipulatorX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ClearGraphsButton     matlab.ui.control.Button
        INVERSEPanel          matlab.ui.container.Panel
        vmaxBox               matlab.ui.control.NumericEditField
        amaxBox               matlab.ui.control.NumericEditField
        PyEditFieldLabel_3    matlab.ui.control.Label
        PxEditFieldLabel_3    matlab.ui.control.Label
        PzEditFieldLabel_3    matlab.ui.control.Label
        Pitch_Inv             matlab.ui.control.NumericEditField
        PLANNINGButton        matlab.ui.control.Button
        Pz_Inv                matlab.ui.control.NumericEditField
        Py_Inv                matlab.ui.control.NumericEditField
        Px_Inv                matlab.ui.control.NumericEditField
        PzEditFieldLabel_2    matlab.ui.control.Label
        PyEditFieldLabel_2    matlab.ui.control.Label
        PxEditFieldLabel_2    matlab.ui.control.Label
        INVERSEButton         matlab.ui.control.Button
        ENDEFFECTORPanel      matlab.ui.container.Panel
        WorkSpaceCheckBox     matlab.ui.control.CheckBox
        Coordinate_Checkbox   matlab.ui.control.CheckBox
        Yaw_EE                matlab.ui.control.NumericEditField
        YawEditFieldLabel     matlab.ui.control.Label
        Roll_EE               matlab.ui.control.NumericEditField
        RollEditFieldLabel    matlab.ui.control.Label
        Pitch_EE              matlab.ui.control.NumericEditField
        PitchEditFieldLabel   matlab.ui.control.Label
        Z_EE                  matlab.ui.control.NumericEditField
        PzEditFieldLabel      matlab.ui.control.Label
        Y_EE                  matlab.ui.control.NumericEditField
        PyEditFieldLabel      matlab.ui.control.Label
        X_EE                  matlab.ui.control.NumericEditField
        PxEditFieldLabel      matlab.ui.control.Label
        FORWARDPanel          matlab.ui.container.Panel
        STOPButton            matlab.ui.control.Button
        Clear                 matlab.ui.control.Button
        RESETButton           matlab.ui.control.Button
        Theta4degSliderLabel  matlab.ui.control.Label
        FORWARDButton         matlab.ui.control.Button
        Theta4EditField       matlab.ui.control.NumericEditField
        Theta3EditField       matlab.ui.control.NumericEditField
        Theta2EditField       matlab.ui.control.NumericEditField
        Theta1EditField       matlab.ui.control.NumericEditField
        Theta4Slider          matlab.ui.control.Slider
        Theta3Slider          matlab.ui.control.Slider
        Theta3degLabel        matlab.ui.control.Label
        Theta2Slider          matlab.ui.control.Slider
        Theta2degSliderLabel  matlab.ui.control.Label
        Theta1Slider          matlab.ui.control.Slider
        Theta1degSliderLabel  matlab.ui.control.Label
        UIAxesVel4            matlab.ui.control.UIAxes
        UIAxesVel3            matlab.ui.control.UIAxes
        UIAxesVel2            matlab.ui.control.UIAxes
        UIAxesVel1            matlab.ui.control.UIAxes
        UIAxesTheta4          matlab.ui.control.UIAxes
        UIAxesTheta3          matlab.ui.control.UIAxes
        UIAxesTheta2          matlab.ui.control.UIAxes
        UIAxesTheta1          matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end


    properties (Access = private) 
%% ======== CÁC THÔNG SỐ ========
    
        % Cờ kiểm tra trạng thái robot (True = Đang chạy, False = Đang rảnh)
        isBusy = false;
        stopRequest = false; % Cờ yêu cầu dừng

        % Khai báo các ma trận  
        T01, T1a, Ta2, T23, T34
        T0a, T02, T03, T04
        
        % Tham số D-H (Denavit-Hartenberg)
        % ========  1 =====  2 ===== a ==== 3 === 4
        theta = [   0       pi/2    -pi/2   0     0];
        d     = [   77      0       0       0     0];
        a     = [   0       128     24      124   126];
        alpha = [   pi/2    0       0       0     0];

        % Góc quay
        old_theta = [0, 0, 0, 0];            
        new_theta = [0, 0, 0, 0];            
        theta_offset_deg = [0, 90, -90, 0]; 
        
        % Ma trận xoay cố định
        Rotate_Y = [cos(pi/2) 0 sin(pi/2) 0; 0 1 0 0; -sin(pi/2) 0 cos(pi/2) 0; 0 0 0 1];
        
        % Biến vẽ đường đi 
        robotPath = [];
        h_PathPlot; % Handle cho đường path

        % Biến lưu trữ kết quả góc Euler
        eulerAngles = []; 

        % Inverse kinematics variables
        Px = 0; Py = 0; Pz = 0;  
        Pitch = 0;
        vmax;           % Lưu vận tốc giới hạn (rad/s)
        amax;           % Lưu gia tốc giới hạn (rad/s^2)
        thoigian = [];  % Lưu mảng thời gian chạy quỹ đạo


        %% ======== GRAPHICS HANDLES ========
        % Lưu trữ handle của các objects để cập nhật thay vì vẽ lại
        h_Base_Surf; h_Base_Top; h_Base_Bot;
        h_BaseBox_Faces;
        
        h_Trus1_Surf; h_Trus1_Top; h_Trus1_Bot;     % Trục khớp 1
        h_Joint1_Surf; h_Joint1_Top; h_Joint1_Bot;  % Khối xám khớp 1
        
        h_Link1_Surf; h_Link1_Top; h_Link1_Bot;
        
        h_Joint2_Box; h_Joint2_Cyl; h_Joint2_Top; h_Joint2_Bot;
        h_Link2_Surf; h_Link2_Top; h_Link2_Bot;
        
        h_Joint3_Surf; h_Joint3_Top; h_Joint3_Bot;
        h_Link3_Surf; h_Link3_Top; h_Link3_Bot;
        
        h_EE_Surf; h_EE_Top; h_EE_Bot;
        
        h_Workspace; % Handle cho workspace

        % Dữ liệu hình học gốc (Pre-calculated geometry)
        geo_UnitCyl_X; geo_UnitCyl_Y; geo_UnitCyl_Z; 
        
        geo_Box_Verts; geo_Box_Faces;                   % Hộp chuẩn cho Joint 2
        geo_BaseBox_X; geo_BaseBox_Y; geo_BaseBox_Z;    % Hộp đế
        geo_EE_X; geo_EE_Y; geo_EE_Z;                   % Hình dáng End Effector

        % Các handle trục tọa độ
        h_Coords = {};
        h_Labels = {}; 
    end
  
    methods (Access = private)
        function T = DH_Matrix(app, n)
                 T = [cos(app.theta(n)),  -cos(app.alpha(n))*sin(app.theta(n)),   sin(app.alpha(n))*sin(app.theta(n)), app.a(n)*cos(app.theta(n));
                      sin(app.theta(n)),   cos(app.alpha(n))*cos(app.theta(n)),  -sin(app.alpha(n))*cos(app.theta(n)), app.a(n)*sin(app.theta(n));
                      0,                   sin(app.alpha(n)),                     cos(app.alpha(n)),                     app.d(n);
                      0,                   0,                                     0,                                     1                        ];
        end
        
        % Hàm khởi tạo dữ liệu hình học (Chạy 1 lần)
        function Init_Geometry_Data(app)
            % 1. Hình trụ chuẩn (Unit Cylinder: Radius=1, Height=1)
            % Quan trọng: Radius phải là 1 để sau này nhân với R mong muốn mới đúng
            [app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z] = cylinder(1, 20); 
            
            % 2. Hộp Joint 2 (Giữ nguyên)
            L = 40; W = 40; H = 40;
            x = [-H/2 H/2 H/2 -H/2 -H/2 H/2 H/2 -H/2];
            y = [-W/2 -W/2 W/2 W/2 -W/2 -W/2 W/2 W/2];
            z = [-L/2 -L/2 -L/2 -L/2  L/2  L/2  L/2  L/2];
            app.geo_Box_Verts = [x; y; z; ones(1,8)];
            app.geo_Box_Faces = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8];

            % 3. Hộp Đế Base (Giữ nguyên)
            L_b = 60; W_b = 80; H_b = 40;
            x1 = -L_b; x2 = L_b/2; y1 = -W_b/2; y2 = W_b/2; z1 = 0; z2 = H_b;
            [X1, Z1] = meshgrid([x1 x2], [z1 z2]); Y1 = y1 * ones(size(X1));
            [X2, Z2] = meshgrid([x1 x2], [z1 z2]); Y2 = y2 * ones(size(X2));
            [Y3, Z3] = meshgrid([y1 y2], [z1 z2]); X3 = x1 * ones(size(Y3));
            [Y4, Z4] = meshgrid([y1 y2], [z1 z2]); X4 = x2 * ones(size(Y4));
            app.geo_BaseBox_X = {X1, X2, X3, X4, [x1 x2 x2 x1], [x1 x2 x2 x1]};
            app.geo_BaseBox_Y = {Y1, Y2, Y3, Y4, [y1 y1 y2 y2], [y1 y1 y2 y2]};
            app.geo_BaseBox_Z = {Z1, Z2, Z3, Z4, [z1 z1 z1 z1], [z2 z2 z2 z2]};

            % 4. End Effector (Giữ nguyên)
            x_e = [ 5,  50,   40,   30,   20,   -20,  -30,   -20,   20,   30,   40,   50,   5];
            z_e = [-10,  -10,  -20,  -20,  -30,  -40,   0,    40,   30,   20,   20,   10,   10];
            y_e = [  10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10,   10 ; ...
                   -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10,  -10];
            app.geo_EE_X = repmat(x_e, 2, 1);
            app.geo_EE_Z = repmat(z_e, 2, 1);
            app.geo_EE_Y = y_e;
            
            % Khởi tạo Plot line cho Path
            hold(app.UIAxes, "on");
            app.h_PathPlot = plot3(app.UIAxes, 0, 0, 0, 'LineWidth', 2, 'Color', 'b');
        end
        
        % Hàm helpers: Biến đổi tọa độ Cylinder
        function [X_new, Y_new, Z_new] = Transform_Cylinder(~, X, Y, Z, T_Matrix, Radius, Scale_Z, Offset_Z, Pre_Rotate)
             % Scale Radius và Height chuẩn xác
             X_mod = X * Radius;
             Y_mod = Y * Radius;
             Z_mod = Z * Scale_Z + Offset_Z;
             
             A = [X_mod(1, :); Y_mod(1, :); Z_mod(1, :); ones(1, size(X, 2))];
             B = [X_mod(2, :); Y_mod(2, :); Z_mod(2, :); ones(1, size(X, 2))];
             
             % Xoay trước khi nhân ma trận DH (Dùng cho Link nằm ngang)
             if nargin > 8 && ~isempty(Pre_Rotate)
                 A = Pre_Rotate * A;
                 B = Pre_Rotate * B;
             end
             
             A_new = T_Matrix * A; 
             B_new = T_Matrix * B;
             
             X_new = [A_new(1, :); B_new(1, :)];
             Y_new = [A_new(2, :); B_new(2, :)];
             Z_new = [A_new(3, :); B_new(3, :)];
        end

        function Draw_Robot(app)
            % Tính toán Kinematics
            app.T01 = DH_Matrix(app,1);
            app.T1a = DH_Matrix(app,2);
            app.Ta2 = DH_Matrix(app,3);
            app.T23 = DH_Matrix(app,4);
            app.T34 = DH_Matrix(app,5);
            
            app.T0a = app.T01*app.T1a;
            app.T02 = app.T0a*app.Ta2;
            app.T03 = app.T02*app.T23;
            app.T04 = app.T03*app.T34;

            %% 1. BASE (Đế)
            if isempty(app.h_Base_Surf)
                % Đế dùng ma trận đơn vị eye(4) vì nó đứng yên
                [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, eye(4), 30, 40, 0, []);
                app.h_Base_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', [0.00 0.45 0.74], 'EdgeColor', 'none');
                app.h_Base_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), [0.00 0.45 0.74]);
                app.h_Base_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), [0.00 0.45 0.74]);
                
                % Hộp đế
                c = [0.00 0.45 0.74];
                for i=1:4
                    surf(app.UIAxes, app.geo_BaseBox_X{i}, app.geo_BaseBox_Y{i}, app.geo_BaseBox_Z{i}, 'FaceColor', c, 'EdgeColor', 'none');
                end
                fill3(app.UIAxes, app.geo_BaseBox_X{5}, app.geo_BaseBox_Y{5}, app.geo_BaseBox_Z{5}, c);
                fill3(app.UIAxes, app.geo_BaseBox_X{6}, app.geo_BaseBox_Y{6}, app.geo_BaseBox_Z{6}, c);
            end

            %% 2. TRỤC NỐI TỪ ĐẾ LÊN KHỚP 1 (Blue Cylinder)
            % SỬA LỖI QUAN TRỌNG: Dùng eye(4) thay vì T01 vì trục này gắn chặt vào đế
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, eye(4), 10, 77, 0, []);
            if isempty(app.h_Trus1_Surf)
                c = [0.80,0.80,0.80];
                app.h_Trus1_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c, 'EdgeColor', 'none');
                app.h_Trus1_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), 'blue');
                app.h_Trus1_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), 'blue');
            else
                set(app.h_Trus1_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn);
                set(app.h_Trus1_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:));
                set(app.h_Trus1_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:));
            end
            
            %% 3. KHỐI XOAY KHỚP 1 (Yellow Cylinder) - T01
             [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T01, 20, 50, -25, []);
             if isempty(app.h_Joint1_Surf)
                c = [0.93,0.69,0.13];
                app.h_Joint1_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c, 'EdgeColor', 'none');
                app.h_Joint1_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c);
                app.h_Joint1_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c);
             else
                set(app.h_Joint1_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn);
                set(app.h_Joint1_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:));
                set(app.h_Joint1_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:));
             end
             
            %% 4. CÁNH TAY 1 (LINK 1 - Gray) - T0a
            % Offset -128 để nối lùi về khớp trước
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T0a, 10, 128, -128, app.Rotate_Y);
            if isempty(app.h_Link1_Surf)
                c = [0.80,0.80,0.80];
                app.h_Link1_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c, 'EdgeColor', 'none');
                app.h_Link1_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c);
                app.h_Link1_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c);
            else
                set(app.h_Link1_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn);
                set(app.h_Link1_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:));
                set(app.h_Link1_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:));
            end
            
            %% 5. KHỚP 2 (Hộp xanh + Trụ vàng)
            % Hộp - T0a
            P_new = app.T0a * app.geo_Box_Verts;
            xn = P_new(1,:); yn = P_new(2,:); zn = P_new(3,:);
            if isempty(app.h_Joint2_Box)
                 app.h_Joint2_Box = patch(app.UIAxes, 'Vertices', [xn' yn' zn'], 'Faces', app.geo_Box_Faces, ...
                 'FaceColor', [0.00,0.45,0.74], 'EdgeColor', 'black');
            else
                 set(app.h_Joint2_Box, 'Vertices', [xn' yn' zn']);
            end
            
            % Cylinder vàng - T02
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T02, 20, 50, -25, []);
            if isempty(app.h_Joint2_Cyl)
                c = [0.93,0.69,0.13];
                app.h_Joint2_Cyl = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c, 'EdgeColor', 'none');
                app.h_Joint2_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c);
                app.h_Joint2_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c);
            else
                set(app.h_Joint2_Cyl, 'XData', Xn, 'YData', Yn, 'ZData', Zn);
                set(app.h_Joint2_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:));
                set(app.h_Joint2_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:));
            end

            %% 6. CÁNH TAY 2 (LINK 2 - Red) - T03
            % Offset -124
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T03, 10, 124, -124, app.Rotate_Y);
            if isempty(app.h_Link2_Surf)
                c = [0.64,0.08,0.18];
                app.h_Link2_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c, 'EdgeColor', 'none');
                app.h_Link2_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), c);
                app.h_Link2_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), c);
            else
                set(app.h_Link2_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn);
                set(app.h_Link2_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:));
                set(app.h_Link2_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:));
            end

            %% 7. KHỚP 3 (Blue) - T03
             [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T03, 20, 50, -25, []);
             if isempty(app.h_Joint3_Surf)
                 c = [0.00,0.45,0.74];
                app.h_Joint3_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c, 'EdgeColor', 'none');
                app.h_Joint3_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), [0.8 0.8 0.8]);
                app.h_Joint3_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), [0.8 0.8 0.8]);
             else
                set(app.h_Joint3_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn);
                set(app.h_Joint3_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:));
                set(app.h_Joint3_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:));
             end
             
             %% 8. CÁNH TAY 3 (LINK 3 - Gray) - T04
             % Offset -126
            [Xn, Yn, Zn] = app.Transform_Cylinder(app.geo_UnitCyl_X, app.geo_UnitCyl_Y, app.geo_UnitCyl_Z, app.T04, 10, 126, -126, app.Rotate_Y);
            if isempty(app.h_Link3_Surf)
                c = [0.80,0.80,0.80];
                app.h_Link3_Surf = surf(app.UIAxes, Xn, Yn, Zn, 'FaceColor', c, 'EdgeColor', 'none');
                app.h_Link3_Top = fill3(app.UIAxes, Xn(2,:), Yn(2,:), Zn(2,:), [0.93,0.69,0.13]);
                app.h_Link3_Bot = fill3(app.UIAxes, Xn(1,:), Yn(1,:), Zn(1,:), [0.93,0.69,0.13]);
            else
                set(app.h_Link3_Surf, 'XData', Xn, 'YData', Yn, 'ZData', Zn);
                set(app.h_Link3_Top, 'XData', Xn(2,:), 'YData', Yn(2,:), 'ZData', Zn(2,:));
                set(app.h_Link3_Bot, 'XData', Xn(1,:), 'YData', Yn(1,:), 'ZData', Zn(1,:));
            end

            %% 9. END EFFECTOR (T04)
            A = [app.geo_EE_X(1, :); app.geo_EE_Y(1, :); app.geo_EE_Z(1, :); ones(1, size(app.geo_EE_X, 2))];  
            B = [app.geo_EE_X(2, :); app.geo_EE_Y(2, :); app.geo_EE_Z(2, :); ones(1, size(app.geo_EE_X, 2))]; 
            A_new = app.T04 * A; B_new = app.T04 * B;        
            
            xe_n = [A_new(1, :); B_new(1, :)];
            ye_n = [A_new(2, :); B_new(2, :)];
            ze_n = [A_new(3, :); B_new(3, :)];

            if isempty(app.h_EE_Surf)
                app.h_EE_Surf = surf(app.UIAxes, xe_n, ye_n, ze_n, 'FaceColor', [0.93,0.69,0.13]);
                app.h_EE_Top = fill3(app.UIAxes, xe_n(1, :), ye_n(1, :), ze_n(1, :), [0.00,0.45,0.74]); 
                app.h_EE_Bot = fill3(app.UIAxes, xe_n(2, :), ye_n(2, :), ze_n(2, :), [0.00,0.45,0.74]); 
            else
                set(app.h_EE_Surf, 'XData', xe_n, 'YData', ye_n, 'ZData', ze_n);
                set(app.h_EE_Top, 'XData', xe_n(1, :), 'YData', ye_n(1, :), 'ZData', ze_n(1, :));
                set(app.h_EE_Bot, 'XData', xe_n(2, :), 'YData', ye_n(2, :), 'ZData', ze_n(2, :));
            end

            %% Cập nhật EE Position & Path
            P_ee = app.T04 * [50; 0; 0; 1];
            app.X_EE.Value = P_ee(1);
            app.Y_EE.Value = P_ee(2);
            app.Z_EE.Value = P_ee(3);

            [~, n] = size(app.robotPath);
            app.robotPath(1, n + 1) = P_ee(1);
            app.robotPath(2, n + 1) = P_ee(2);
            app.robotPath(3, n + 1) = P_ee(3);  
            set(app.h_PathPlot, 'XData', app.robotPath(1, :), 'YData', app.robotPath(2, :), 'ZData', app.robotPath(3, :));

            %% Workspace
            if isprop(app,'WorkSpaceCheckBox') && app.WorkSpaceCheckBox.Value
                if isempty(app.h_Workspace)
                    r = 450; z_center = 77;
                    [u, v] = meshgrid(linspace(0, 2*pi, 20), linspace(0, pi, 20));
                    x = r .* cos(u) .* sin(v);
                    y = r .* sin(u) .* sin(v);
                    z = r .* cos(v) + z_center;
                    hold(app.UIAxes, "on");
                    app.h_Workspace = surf(app.UIAxes, x, y, z, 'EdgeColor', 'none', 'FaceColor', [0.5 0.5 0.5], 'FaceAlpha', 0.15);
                else
                    app.h_Workspace.Visible = 'on';
                end
            elseif ~isempty(app.h_Workspace)
                app.h_Workspace.Visible = 'off';
            end

            %% Euler Angles
            cospitch = sqrt((app.T04(1, 1))^2 + (app.T04(2, 1))^2);     
            pitch = atan2(-app.T04(3, 1), cospitch);                    

            if pitch == pi / 2           
                yaw = 0; roll = atan2(app.T04(1, 2), app.T04(2, 2));             
            elseif pitch == -pi / 2
                yaw = 0; roll = atan2(-app.T04(1, 2), app.T04(2, 2));            
            else
                yaw = atan2(app.T04(2, 1) / cos(pitch), app.T04(1, 1) / cos(pitch));    
                roll = atan2(app.T04(3, 2) / cos(pitch), app.T04(3, 3) / cos(pitch));  
            end
            app.eulerAngles = [roll pitch yaw] * (180 / pi); 

            app.Roll_EE.Value    = app.eulerAngles(1);
            app.Pitch_EE.Value   = app.eulerAngles(2);
            app.Yaw_EE.Value     = app.eulerAngles(3);  
            
            % Update Coordinates
            app.Update_Coordinates();
        end        
        % Hàm chạy Animation chung (Có tính năng STOP)
        function Animate_Robot(app)
            % Cấu hình tốc độ
            steps = 30;     
            delay = 0.0001;
            
            % Reset cờ dừng trước khi chạy
            app.stopRequest = false;
            
            theta_start = app.old_theta;
            theta_end = [app.new_theta(1), app.new_theta(2), app.new_theta(3), app.new_theta(4)];
            
            delta = (theta_end - theta_start) / steps;
            
            current = theta_start;
            
            for i = 1:steps
                % --- KIỂM TRA STOP ---
                if app.stopRequest
                    % 1. Cập nhật vị trí hiện tại làm vị trí cũ (để lần sau chạy tiếp từ đây)
                    app.old_theta = current;
                    app.new_theta = current; % Hủy đích đến cũ
                    
                    % 2. Đồng bộ ngược lại UI (Slider/EditField) về vị trí đang đứng
                    % Nếu không làm bước này, Slider sẽ lệch với hình ảnh robot
                    offsets = [0, 90, -90, 0];
                    sliders = {app.Theta1Slider, app.Theta2Slider, app.Theta3Slider, app.Theta4Slider};
                    fields  = {app.Theta1EditField, app.Theta2EditField, app.Theta3EditField, app.Theta4EditField};
                    
                    for k=1:4
                        val_deg = rad2deg(current(k)) - offsets(k);
                        % Chuẩn hóa và kẹp giá trị như logic cũ
                        val_deg = mod(val_deg + 180, 360) - 180;
                        if val_deg < sliders{k}.Limits(1), val_deg = sliders{k}.Limits(1); end
                        if val_deg > sliders{k}.Limits(2), val_deg = sliders{k}.Limits(2); end
                        
                        sliders{k}.Value = val_deg;
                        fields{k}.Value = val_deg;
                    end

                    % 3. Thông báo
                    uialert(app.UIFigure, 'Emergency stop activated!', 'Alert', 'Icon', 'warning');    

                    % 4. Reset trạng thái và thoát vòng lặp
                    app.stopRequest = false;
                    app.isBusy = false; 
                    return; % Thoát hàm ngay lập tức
                end
                % ---------------------
                
                current = current + delta;
                app.theta(1) = current(1);
                app.theta(2) = current(2);
                app.theta(3) = current(3);
                app.theta(5) = current(4); 
                
                app.Draw_Robot();
                
                drawnow; 
                pause(delay); 
            end
            app.old_theta = theta_end; % Cập nhật vị trí cũ nếu chạy hết hành trình
        end
        
  
function Update_Coordinates(app)
            % 1. Xử lý khi tắt Checkbox (Ẩn hết)
            if ~app.Coordinate_Checkbox.Value
                if ~isempty(app.h_Coords)
                     for i=1:length(app.h_Coords)
                         if isvalid(app.h_Coords{i}), app.h_Coords{i}.Visible = 'off'; end
                     end
                     % Ẩn luôn nhãn chữ
                     for i=1:length(app.h_Labels)
                         if isvalid(app.h_Labels{i}), app.h_Labels{i}.Visible = 'off'; end
                     end
                end
                return;
            end
            
            % Định nghĩa tên trục tương ứng với vòng lặp (1=X, 2=Y, 3=Z)
            axis_names = {'X', 'Y', 'Z'};

            % 2. Khởi tạo lần đầu (Nếu chưa vẽ bao giờ)
            if isempty(app.h_Coords)
                 app.h_Coords = {}; 
                 app.h_Labels = {}; % Reset thùng chứa nhãn
                 
                 matrices = {eye(4), app.T01, app.T0a, app.T02, app.T03, app.T04};
                 colors = {'r', 'g', 'b'};
                 
                 for m=1:length(matrices)
                     origin = matrices{m} * [0 0 0 1]';
                     for axis=1:3
                         vec = [0 0 0 1]'; vec(axis) = 100; % Độ dài trục 100
                         pt = matrices{m} * vec;
                         
                         % Vẽ mũi tên
                         q = quiver3(app.UIAxes, origin(1), origin(2), origin(3), ...
                             pt(1)-origin(1), pt(2)-origin(2), pt(3)-origin(3), ...
                             colors{axis}, 'LineWidth', 2);
                         app.h_Coords{end+1} = q;
                         
                         % [MỚI] Vẽ chữ X, Y, Z tại đầu mũi tên
                         txt = text(app.UIAxes, pt(1), pt(2), pt(3), axis_names{axis}, ...
                             'Color', colors{axis}, 'FontWeight', 'bold', 'FontSize', 8);
                         app.h_Labels{end+1} = txt;
                     end
                 end
                 
            % 3. Cập nhật vị trí (Nếu đã vẽ rồi)
            else
                 idx = 1;
                 matrices = {eye(4), app.T01, app.T0a, app.T02, app.T03, app.T04};
                 
                 for m=1:length(matrices)
                     origin = matrices{m} * [0 0 0 1]';
                     for axis=1:3
                         vec = [0 0 0 1]'; vec(axis) = 100;
                         pt = matrices{m} * vec;
                         
                         % Cập nhật mũi tên
                         if idx <= length(app.h_Coords) && isvalid(app.h_Coords{idx})
                             set(app.h_Coords{idx}, 'XData', origin(1), 'YData', origin(2), 'ZData', origin(3), ...
                                 'UData', pt(1)-origin(1), 'VData', pt(2)-origin(2), 'WData', pt(3)-origin(3), ...
                                 'Visible', 'on');
                         end
                         
                         % [MỚI] Cập nhật vị trí chữ
                         if idx <= length(app.h_Labels) && isvalid(app.h_Labels{idx})
                             set(app.h_Labels{idx}, 'Position', pt(1:3), 'Visible', 'on');
                         end
                         
                         idx = idx + 1;
                     end
                 end
            end
        end


        % Hàm tính toán và vẽ đồ thị quy hoạch quỹ đạo
        function LSPB_TrajectoryPlanning(app)
%% --- CẤU HÌNH BAN ĐẦU ---
            num_joints = 4;
            dt = 0.05; % Bước thời gian (Sample time)
            
            % Lấy dữ liệu quãng đường (Delta Theta)
            delta_Q = app.new_theta(1:num_joints) - app.old_theta(1:num_joints);
            dist = abs(delta_Q); 
            
            V_limit = repmat(app.vmax, 1, num_joints); 
            A_limit = repmat(app.amax, 1, num_joints); 
            
            %% --- PHẦN 1: TÍNH TOÁN ĐỒNG BỘ THỜI GIAN ---
            T_needed = zeros(1, num_joints);
            
            for k = 1:num_joints
                if dist(k) < 1e-4 % Nếu khớp không di chuyển đáng kể
                    T_needed(k) = 0;
                    continue;
                end
                
                v_peak_possible = sqrt(dist(k) * A_limit(k)); 
                V_use = V_limit(k); 
                if V_use > v_peak_possible
                    V_use = v_peak_possible * 0.95; 
                end
                
                t_acc = V_use / A_limit(k);
                t_flat = (dist(k) - V_use*t_acc) / V_use;
                T_needed(k) = 2 * t_acc + t_flat;
            end
            
            T_common = max(T_needed);
            
            if T_common == 0
                return; % Không cần di chuyển
            end
            
            app.thoigian = 0:dt:T_common;
            len_t = length(app.thoigian);
            
            %% --- PHẦN 2: TÍNH TOÁN LẠI VẬN TỐC ĐỒNG BỘ ---
            V_sync = zeros(1, num_joints);
            
            for k = 1:num_joints
                if dist(k) < 1e-4, continue; end
                
                delta_eq = (A_limit(k)*T_common)^2 - 4 * A_limit(k) * dist(k);
                
                if delta_eq >= 0
                    V_sync(k) = (A_limit(k)*T_common - sqrt(delta_eq)) / 2;
                else
                    V_sync(k) = dist(k) / T_common * 1.5; 
                end
            end

            %% --- PHẦN 3: TẠO QUỸ ĐẠO ---
            Q_traj = zeros(num_joints, len_t); 
            V_traj = zeros(num_joints, len_t);
            
            for k = 1:num_joints
                if dist(k) < 1e-4
                    Q_traj(k, :) = app.old_theta(k); 
                    continue; 
                end
                
                v = V_sync(k); acc = A_limit(k);
                t_acc = v / acc; t_dec = T_common - t_acc;
                sign_q = sign(delta_Q(k)); 
                
                for i = 1:len_t
                    t = app.thoigian(i);
                    
                    if t < t_acc
                        q_val = 0.5 * acc * t^2; v_val = acc * t;
                    elseif t <= t_dec
                        q_acc = 0.5 * acc * t_acc^2;
                        q_val = q_acc + v * (t - t_acc); v_val = v;
                    elseif t <= T_common
                        t_rem = T_common - t;
                        q_val = dist(k) - 0.5 * acc * t_rem^2; v_val = acc * t_rem;
                    else
                        q_val = dist(k); v_val = 0;
                    end
                    
                    Q_traj(k, i) = app.old_theta(k) + sign_q * q_val;
                    V_traj(k, i) = sign_q * v_val;
                end
            end
            
            %% --- PHẦN 4: VẼ ĐỒ THỊ & ANIMATION (ĐÃ SỬA OFFSET) ---
            hold(app.UIAxesTheta1, 'off'); hold(app.UIAxesTheta2, 'off');
            hold(app.UIAxesTheta3, 'off'); hold(app.UIAxesTheta4, 'off');
            hold(app.UIAxesVel1, 'off');   hold(app.UIAxesVel2, 'off');
            hold(app.UIAxesVel3, 'off');   hold(app.UIAxesVel4, 'off');

            for i = 1:len_t
                if app.stopRequest
                    app.stopRequest = false;
                    app.new_theta(1) = Q_traj(1, i);
                    app.new_theta(2) = Q_traj(2, i);
                    app.new_theta(3) = Q_traj(3, i);
                    app.new_theta(4) = Q_traj(4, i);
                    uialert(app.UIFigure, 'Đã dừng quy hoạch!', 'Thông báo');
                    return; 
                end

                % Cập nhật robot (Dùng Q_traj radian chuẩn DH để vẽ robot)
                app.theta(1) = Q_traj(1, i);
                app.theta(2) = Q_traj(2, i);
                app.theta(3) = Q_traj(3, i);
                app.theta(5) = Q_traj(4, i); 
                
                app.Draw_Robot();
                
                % Vẽ đồ thị (Vẽ đè dần lên)
                if mod(i, 2) == 0 || i == len_t
                    t_curr = app.thoigian(1:i);
                    
                    % --- SỬA LỖI Ở ĐÂY: Trừ Offset để đồ thị khớp với GUI ---
                    
                    % Khớp 1: Trừ Offset(1)
                    data_theta1 = rad2deg(Q_traj(1, 1:i)) - app.theta_offset_deg(1);
                    plot(app.UIAxesTheta1, t_curr, data_theta1, 'b', 'LineWidth', 1.5); xlim(app.UIAxesTheta1, [0 T_common]); 
                    plot(app.UIAxesVel1, t_curr, rad2deg(V_traj(1, 1:i)), 'r', 'LineWidth', 1.5); xlim(app.UIAxesVel1, [0 T_common]);

                    % Khớp 2: Trừ Offset(2)
                    data_theta2 = rad2deg(Q_traj(2, 1:i)) - app.theta_offset_deg(2);
                    plot(app.UIAxesTheta2, t_curr, data_theta2, 'b', 'LineWidth', 1.5); xlim(app.UIAxesTheta2, [0 T_common]);
                    plot(app.UIAxesVel2, t_curr, rad2deg(V_traj(2, 1:i)), 'r', 'LineWidth', 1.5); xlim(app.UIAxesVel2, [0 T_common]);

                    % Khớp 3: Trừ Offset(3)
                    data_theta3 = rad2deg(Q_traj(3, 1:i)) - app.theta_offset_deg(3);
                    plot(app.UIAxesTheta3, t_curr, data_theta3, 'b', 'LineWidth', 1.5); xlim(app.UIAxesTheta3, [0 T_common]);
                    plot(app.UIAxesVel3, t_curr, rad2deg(V_traj(3, 1:i)), 'r', 'LineWidth', 1.5); xlim(app.UIAxesVel3, [0 T_common]);

                    % Khớp 4: Trừ Offset(4)
                    data_theta4 = rad2deg(Q_traj(4, 1:i)) - app.theta_offset_deg(4);
                    plot(app.UIAxesTheta4, t_curr, data_theta4, 'b', 'LineWidth', 1.5); xlim(app.UIAxesTheta4, [0 T_common]);
                    plot(app.UIAxesVel4, t_curr, rad2deg(V_traj(4, 1:i)), 'r', 'LineWidth', 1.5); xlim(app.UIAxesVel4, [0 T_common]);
                end

                drawnow limitrate;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            
            % Init Geometry
            app.Init_Geometry_Data();

            % Góc ban đầu của robot (rad): [0, 90, -90, 0]
            app.theta(1) = deg2rad(app.theta_offset_deg(1)); % 0
            app.theta(2) = deg2rad(app.theta_offset_deg(2)); % 90
            app.theta(3) = deg2rad(app.theta_offset_deg(3)); % -90
            app.theta(5) = deg2rad(app.theta_offset_deg(4)); % 0

            app.old_theta = app.theta(1:4);
            app.robotPath = [];
            
            app.Theta1Slider.Value = 0;
            app.Theta2Slider.Value = 0;
            app.Theta3Slider.Value = 0;
            app.Theta4Slider.Value = 0;

            app.Theta1EditField.Value = 0;
            app.Theta2EditField.Value = 0 ;
            app.Theta3EditField.Value = 0 ;
            app.Theta4EditField.Value = 0 ;

% --- [MỚI] CÀI ĐẶT GIÁ TRỊ MẶC ĐỊNH (INVERSE & PLANNING) ---
            % 1. Gán giá trị hiển thị lên ô nhập liệu
            app.Px_Inv.Value = 324;
            app.Py_Inv.Value = 0;
            app.Pz_Inv.Value = 205;
            
            app.vmaxBox.Value = 20;
            app.amaxBox.Value = 20;
            
            % 2. Cập nhật biến nội tại
            app.Px = 304;
            app.Py = 0;
            app.Pz = 204 - app.d(1); 
            
            app.vmax = deg2rad(20); 
            app.amax = deg2rad(20);


            app.Draw_Robot();

        end

        % Button pushed function: FORWARDButton
        function FORWARDButtonPushed(app, event)
            if app.isBusy
                uialert(app.UIFigure, 'Robot is moving! Please wait for the operation to finish.', 'System Busy', 'Icon', 'warning');                return; 
            end
            app.isBusy = true; % Khóa
            % Lấy giá trị từ UI
            ui1 = app.Theta1Slider.Value;
            ui2 = app.Theta2Slider.Value;
            ui3 = app.Theta3Slider.Value;
            ui4 = app.Theta4Slider.Value;
            
            % Update EditFields
            app.Theta1EditField.Value = ui1;
            app.Theta2EditField.Value = ui2;
            app.Theta3EditField.Value = ui3;
            app.Theta4EditField.Value = ui4;
            
            % Tính góc thực tế
            app.new_theta(1) = deg2rad(ui1 + app.theta_offset_deg(1));
            app.new_theta(2) = deg2rad(ui2 + app.theta_offset_deg(2));
            app.new_theta(3) = deg2rad(ui3 + app.theta_offset_deg(3));
            app.new_theta(4) = deg2rad(ui4 + app.theta_offset_deg(4));

            % Chạy Animation
            app.Animate_Robot();
            app.isBusy = false;
        end

        % Value changed function: Theta1Slider
        function Theta1SliderValueChanged(app, event)
                ui1 = app.Theta1Slider.Value;
                app.Theta1EditField.Value = ui1;
                app.new_theta(1) = deg2rad(ui1 + app.theta_offset_deg(1));
        end

        % Value changed function: Theta2Slider
        function Theta2SliderValueChanged(app, event)
                ui2 = app.Theta2Slider.Value;
                app.Theta2EditField.Value = ui2;
                app.new_theta(2) = deg2rad(ui2 + app.theta_offset_deg(2));
        end

        % Value changed function: Theta3Slider
        function Theta3SliderValueChanged(app, event)
                ui3 = app.Theta3Slider.Value;
                app.Theta3EditField.Value = ui3;
                app.new_theta(3) = deg2rad(ui3 + app.theta_offset_deg(3));
        end

        % Value changed function: Theta4Slider
        function Theta4SliderValueChanged(app, event)
                ui4 = app.Theta4Slider.Value;
                app.Theta4EditField.Value = ui4;
                app.new_theta(4) = deg2rad(ui4 + app.theta_offset_deg(4));
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            if app.isBusy
                uialert(app.UIFigure, 'Robot is moving! Please wait for the operation to finish.', 'System Busy', 'Icon', 'warning');                
                return; 
            end
            
            app.isBusy = true; % Khóa
            
            % Reset UI về 0
            app.Theta1Slider.Value = 0; app.Theta1EditField.Value = 0;
            app.Theta2Slider.Value = 0; app.Theta2EditField.Value = 0;
            app.Theta3Slider.Value = 0; app.Theta3EditField.Value = 0;
            app.Theta4Slider.Value = 0; app.Theta4EditField.Value = 0;
            
            % Tính target
            for j = 1:4
                app.new_theta(j) = deg2rad(0 + app.theta_offset_deg(j));
            end
            
            % Chạy Animation
            app.Animate_Robot();

            app.isBusy = false; % Khóa
        end

        % Button pushed function: Clear
        function ClearButtonPushed(app, event)
             app.robotPath = [];
            set(app.h_PathPlot, 'XData', [], 'YData', [], 'ZData', []);
        end

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            app.Theta1Slider.Value = app.Theta1EditField.Value;
            app.new_theta(1) = deg2rad(app.Theta1EditField.Value + app.theta_offset_deg(1));
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
            app.Theta2Slider.Value = app.Theta2EditField.Value;
            app.new_theta(2) = deg2rad(app.Theta2EditField.Value + app.theta_offset_deg(2));
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            app.Theta3Slider.Value = app.Theta3EditField.Value;
            app.new_theta(3) = deg2rad(app.Theta3EditField.Value + app.theta_offset_deg(3));
        end

        % Value changed function: Theta4EditField
        function Theta4EditFieldValueChanged(app, event)
            app.Theta4Slider.Value = app.Theta4EditField.Value;
            app.new_theta(4) = deg2rad(app.Theta4EditField.Value + app.theta_offset_deg(4));
        end

        % Value changed function: Coordinate_Checkbox
        function Coordinate_CheckboxValueChanged(app, event)
            app.Update_Coordinates();
        end

        % Value changed function: WorkSpaceCheckBox
        function WorkSpaceCheckBoxValueChanged(app, event)
            app.Draw_Robot();
        end

        % Button pushed function: INVERSEButton
        function INVERSEButtonPushed(app, event)
            if app.isBusy
                uialert(app.UIFigure, 'Robot is moving! Please wait for the operation to finish.', 'System Busy', 'Icon', 'warning');               
                return; 
            end
            app.isBusy = true; % Khóa
            % 1. CẤU HÌNH THAM SỐ ROBOT
            d1 = 77; L2 = 128; L3 = 24 + 124; L4 = 126 + 50;
            offsets = [0, 90, -90, 0]; 

            % 2. ĐỌC DỮ LIỆU TỪ GIAO DIỆN
            px = app.Px_Inv.Value;
            py = app.Py_Inv.Value;
            pz = app.Pz_Inv.Value;
            pitch_val = app.Pitch_Inv.Value;

            % --- KIỂM TRA INPUT CƠ BẢN ---
            % Ví dụ: Không cho nhập Z quá thấp dưới mặt đất (tùy chọn)
            if pz <= 0
                    uialert(app.UIFigure, 'Pz value must be > 0!', 'Input Warning', 'Icon', 'warning');                 
                    app.isBusy = false;
                 return;
            end

            % Input Pitch -90 (thẳng đứng) -> Tính toán +90
            phi = deg2rad(-pitch_val);

            % 3. XỬ LÝ GÓC ĐẾ & BÁN KÍNH
            theta1_raw = atan2(py, px);
            pr = sqrt(px^2 + py^2);

            if abs(theta1_raw) > pi/2
                if theta1_raw > 0, theta1 = theta1_raw - pi;
                else, theta1 = theta1_raw + pi;
                end
                pr = -pr; 
            else
                theta1 = theta1_raw;
            end

            % 4. TÍNH TOÁN IK
            r_ee = pr;      
            z_ee = pz - d1;
            
            % Tính tọa độ tâm cổ tay (Wrist Center)
            r_wc = r_ee - L4 * cos(phi);
            z_wc = z_ee - L4 * sin(phi);

            numerator = r_wc^2 + z_wc^2 - L2^2 - L3^2;
            denominator = 2 * L2 * L3;
            D = numerator / denominator;

            % --- [QUAN TRỌNG] KIỂM TRA VÙNG LÀM VIỆC ---
            if abs(D) > 1
                % Nếu D > 1 hoặc D < -1 nghĩa là tam giác không thể hình thành
                % Robot không với tới điểm này
                uialert(app.UIFigure, ...
                    sprintf('Target is outside the workspace!\nRobot cannot reach:\nPx=%.1f, Py=%.1f, Pz=%.1f', px, py, pz), ...
                    'Inverse Kinematics Error', ...
                    'Icon', 'error');
                app.isBusy = false;
                return; % Dừng hàm ngay lập tức, không chạy tiếp
            end
            
            % Nếu thỏa mãn thì tính tiếp
            theta3_geom = -acos(D); 
            k1 = L2 + L3 * cos(theta3_geom);
            k2 = L3 * sin(theta3_geom);
            theta2_geom = atan2(z_wc, r_wc) - atan2(k2, k1);
            theta4_geom = phi - theta2_geom - theta3_geom;

            % 5. CẬP NHẬT KẾT QUẢ VÀO BIẾN new_theta
            app.new_theta(1) = theta1;
            app.new_theta(2) = theta2_geom;
            app.new_theta(3) = theta3_geom;
            app.new_theta(4) = theta4_geom;

            % --- ĐOẠN CODE FIX LỖI GIỚI HẠN SLIDER ---
            % Gom các giá trị góc tính được vào mảng
            raw_angles = [ ...
                rad2deg(theta1) - offsets(1), ...
                rad2deg(theta2_geom) - offsets(2), ...
                rad2deg(theta3_geom) - offsets(3), ...
                rad2deg(theta4_geom) - offsets(4) ...
            ];
            
            % Gom các Slider và EditField vào mảng để xử lý vòng lặp
            sliders = {app.Theta1Slider, app.Theta2Slider, app.Theta3Slider, app.Theta4Slider};
            fields  = {app.Theta1EditField, app.Theta2EditField, app.Theta3EditField, app.Theta4EditField};

            for i = 1:4
                val = raw_angles(i);
                
                % Bước 1: Chuẩn hóa góc về khoảng -180 đến 180
                % Ví dụ: 190 độ sẽ thành -170 độ
                val = mod(val + 180, 360) - 180;
                
                % Bước 2: Kẹp giá trị (Safety Clamp)
                % Đề phòng trường hợp tính ra 180.00001 làm lỗi slider
                limit_min = sliders{i}.Limits(1);
                limit_max = sliders{i}.Limits(2);
                
                if val < limit_min, val = limit_min; end
                if val > limit_max, val = limit_max; end
                
                % Bước 3: Gán giá trị an toàn
                sliders{i}.Value = val;
                fields{i}.Value  = val;
            end
            % -----------------------------------------

            % Chạy animation đến điểm mới
            app.Animate_Robot();


            app.isBusy = false  ; % Khóa
        end

        % Value changed function: Px_Inv
        function Px_InvValueChanged(app, event)
            app.Px = app.Px_Inv.Value;
  
        end

        % Value changed function: Py_Inv
        function Py_InvValueChanged(app, event)
            app.Py = app.Py_Inv.Value;
            
        end

        % Value changed function: Pz_Inv
        function Pz_InvValueChanged(app, event)
            app.Pz = app.Pz_Inv.Value - app.d(1);
            
        end

        % Value changed function: Pitch_Inv
        function Pitch_InvValueChanged(app, event)
            app.Pitch  = app.Pitch_Inv.Value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.isBusy
                app.stopRequest = true; % Gửi tín hiệu dừng
                % Không set isBusy = false ở đây, để vòng lặp tự xử lý
            end
        end

        % Button pushed function: PLANNINGButton
        function PLANNINGButtonPushed(app, event)
            if app.isBusy
                uialert(app.UIFigure, 'Robot is moving! Please wait for the operation to finish.', 'System Busy', 'Icon', 'warning');                
                return; 
            end
            
            app.isBusy = true; % Khóa hệ thống
            
            %% ===== 0. KIỂM TRA ĐẦU VÀO VẬN TỐC/GIA TỐC =====
            % Lấy giá trị từ box (đề phòng biến app.vmax chưa cập nhật)
            v_val = app.vmaxBox.Value;
            a_val = app.amaxBox.Value;
            
            if v_val <= 0 || a_val <= 0
                uialert(app.UIFigure, 'Vmax and Amax must be greater than 0.', 'Input Error');
                app.isBusy = false;
                return;
            end
            
            % Đổi sang rad/s và lưu vào biến
            app.vmax = deg2rad(v_val);
            app.amax = deg2rad(a_val);

            %% ===== 1. CẤU HÌNH THAM SỐ ROBOT =====
            d1 = 77; L2 = 128; L3 = 148; L4 = 176; % L3=24+124, L4=126+50
            offsets = [0, 90, -90, 0]; 

            %% ===== 2. ĐỌC DỮ LIỆU =====
            px = app.Px_Inv.Value;
            py = app.Py_Inv.Value;
            pz = app.Pz_Inv.Value;
            pitch_val = app.Pitch_Inv.Value;
            phi = deg2rad(-pitch_val);

            %% ===== 3. XỬ LÝ GÓC ĐẾ & BÁN KÍNH =====
            theta1_raw = atan2(py, px);
            pr = sqrt(px^2 + py^2);

            if abs(theta1_raw) > pi/2
                if theta1_raw > 0, theta1 = theta1_raw - pi;
                else, theta1 = theta1_raw + pi;
                end
                pr = -pr; 
            else
                theta1 = theta1_raw;
            end

            %% ===== 4. TÍNH TOÁN IK (INVERSE KINEMATICS) =====
            r_ee = pr;      
            z_ee = pz - d1;
            r_wc = r_ee - L4 * cos(phi);
            z_wc = z_ee - L4 * sin(phi);

            numerator = r_wc^2 + z_wc^2 - L2^2 - L3^2;
            denominator = 2 * L2 * L3;
            D = numerator / denominator;

            % Kiểm tra vùng làm việc
            if abs(D) > 1
                 uialert(app.UIFigure, 'Target unreachable!', 'Error');
                 app.isBusy = false;
                 return;
            end
            
            if D > 1, D = 1; elseif D < -1, D = -1; end
            
            theta3_geom = -acos(D); 
            k1 = L2 + L3 * cos(theta3_geom);
            k2 = L3 * sin(theta3_geom);
            theta2_geom = atan2(z_wc, r_wc) - atan2(k2, k1);
            theta4_geom = phi - theta2_geom - theta3_geom;

            %% ===== 5. CẬP NHẬT KẾT QUẢ VÀO BIẾN ĐÍCH (NEW_THETA) =====
            % Lưu ý: KHÔNG cập nhật old_theta ở đây ngay
            app.new_theta(1) = theta1;
            app.new_theta(2) = theta2_geom;
            app.new_theta(3) = theta3_geom;
            app.new_theta(4) = theta4_geom; 

            % Cập nhật Slider/EditField hiển thị đích đến (Trừ Offset để hiển thị đúng 0,0,0,0)
            app.Theta1EditField.Value = rad2deg(theta1) - offsets(1);
            app.Theta1Slider.Value        = rad2deg(theta1) - offsets(1);

            app.Theta2EditField.Value = rad2deg(theta2_geom) - offsets(2);
            app.Theta2Slider.Value        = rad2deg(theta2_geom) - offsets(2);

            app.Theta3EditField.Value = rad2deg(theta3_geom) - offsets(3);
            app.Theta3Slider.Value        = rad2deg(theta3_geom) - offsets(3);

            app.Theta4EditField.Value = rad2deg(theta4_geom) - offsets(4);
            app.Theta4Slider.Value        = rad2deg(theta4_geom) - offsets(4);

            %% ===== 6. CHẠY QUY HOẠCH QUỸ ĐẠO =====
            % Gọi hàm vẽ đồ thị
            app.LSPB_TrajectoryPlanning();
            
            % Sau khi chạy xong, cập nhật vị trí cũ thành vị trí mới cho lần chạy sau
            app.old_theta = app.new_theta;

            app.isBusy = false; % Mở khóa
        end

        % Button pushed function: ClearGraphsButton
        function ClearGraphsButtonPushed(app, event)
            % 1. KIỂM TRA TRẠNG THÁI ROBOT
            if app.isBusy
                uialert(app.UIFigure, 'Robot is moving! Please wait for the operation to finish.', 'System Busy', 'Icon', 'warning');                
                return; 
            end
            
            % 2. THỰC HIỆN XÓA NẾU ROBOT ĐANG ĐỨNG YÊN
            % Xóa đồ thị góc (Theta)
            cla(app.UIAxesTheta1);
            cla(app.UIAxesTheta2);
            cla(app.UIAxesTheta3);
            cla(app.UIAxesTheta4);
            
            % Xóa đồ thị vận tốc (Velocity)
            cla(app.UIAxesVel1);
            cla(app.UIAxesVel2);
            cla(app.UIAxesVel3);
            cla(app.UIAxesVel4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Manipulator X')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-500 500];
            app.UIAxes.YLim = [-500 500];
            app.UIAxes.ZLim = [0 700];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.UIAxes.XTickLabel = {'-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-500 -400 -300 -200 -100 0 100 200 300 400 500];
            app.UIAxes.YTickLabel = {'-500'; '-400'; '-300'; '-200'; '-100'; '0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes.ZTick = [0 100 200 300 400 500 600];
            app.UIAxes.ZTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'};
            app.UIAxesTheta1 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta1, 'Theta1 (deg)')
            xlabel(app.UIAxesTheta1, 't (s)')
            ylabel(app.UIAxesTheta1, 'P1(t) ')
            app.UIAxesTheta2 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta2, 'Theta2 (deg)')
            xlabel(app.UIAxesTheta2, 't (s)')
            ylabel(app.UIAxesTheta2, 'P2(t) ')
            app.UIAxesTheta3 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta3, 'Theta3 (deg)')
            xlabel(app.UIAxesTheta3, 't (s)')
            ylabel(app.UIAxesTheta3, 'P3(t) ')
            app.UIAxesTheta4 = uiaxes(app.UIFigure);
            title(app.UIAxesTheta4, 'Theta4 (deg)')
            xlabel(app.UIAxesTheta4, 't (s)')
            ylabel(app.UIAxesTheta4, 'P4(t) ')
            app.UIAxesVel1 = uiaxes(app.UIFigure);
            title(app.UIAxesVel1, 'Velocity1 (deg/s)')
            xlabel(app.UIAxesVel1, 't (s)')
            ylabel(app.UIAxesVel1, 'v1(t)')
            app.UIAxesVel2 = uiaxes(app.UIFigure);
            title(app.UIAxesVel2, 'Velocity2 (deg/s)')
            xlabel(app.UIAxesVel2, 't (s)')
            ylabel(app.UIAxesVel2, 'v2(t)')
            app.UIAxesVel3 = uiaxes(app.UIFigure);
            title(app.UIAxesVel3, 'Velocity3 (deg/s)')
            xlabel(app.UIAxesVel3, 't (s)')
            ylabel(app.UIAxesVel3, 'v3(t)')
            app.UIAxesVel4 = uiaxes(app.UIFigure);
            title(app.UIAxesVel4, 'Velocity4(deg/s)')
            xlabel(app.UIAxesVel4, 't (s)')
            ylabel(app.UIAxesVel4, 'v4(t)')
            app.FORWARDPanel = uipanel(app.UIFigure);
            app.FORWARDPanel.Title = 'FORWARD';
            app.Theta1degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta1degSliderLabel.Text = {'Theta1'; '(deg)'};
            app.Theta1Slider = uislider(app.FORWARDPanel);
            app.Theta1Slider.Limits = [-180 180];
            app.Theta1Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta1Slider.ValueChangedFcn = createCallbackFcn(app, @Theta1SliderValueChanged, true);
            app.Theta2degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta2degSliderLabel.Text = {'Theta2'; '(deg)'};
            app.Theta2Slider = uislider(app.FORWARDPanel);
            app.Theta2Slider.Limits = [-180 180];
            app.Theta2Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta2Slider.ValueChangedFcn = createCallbackFcn(app, @Theta2SliderValueChanged, true);
            app.Theta3degLabel = uilabel(app.FORWARDPanel);
            app.Theta3degLabel.Text = {'Theta3'; '(deg)'};
            app.Theta3Slider = uislider(app.FORWARDPanel);
            app.Theta3Slider.Limits = [-180 180];
            app.Theta3Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta3Slider.ValueChangedFcn = createCallbackFcn(app, @Theta3SliderValueChanged, true);
            app.Theta4Slider = uislider(app.FORWARDPanel);
            app.Theta4Slider.Limits = [-180 180];
            app.Theta4Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Theta4Slider.ValueChangedFcn = createCallbackFcn(app, @Theta4SliderValueChanged, true);
            app.Theta1EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta1EditField.ValueDisplayFormat = '%.2f';
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta2EditField.ValueDisplayFormat = '%.2f';
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta3EditField.ValueDisplayFormat = '%.2f';
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.Theta4EditField = uieditfield(app.FORWARDPanel, 'numeric');
            app.Theta4EditField.ValueDisplayFormat = '%.2f';
            app.Theta4EditField.ValueChangedFcn = createCallbackFcn(app, @Theta4EditFieldValueChanged, true);
            app.FORWARDButton = uibutton(app.FORWARDPanel, 'push');
            app.FORWARDButton.ButtonPushedFcn = createCallbackFcn(app, @FORWARDButtonPushed, true);
            app.FORWARDButton.Text = 'FORWARD';
            app.Theta4degSliderLabel = uilabel(app.FORWARDPanel);
            app.Theta4degSliderLabel.Text = {'Theta4'; '(deg)'};
            app.RESETButton = uibutton(app.FORWARDPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.Clear = uibutton(app.FORWARDPanel, 'push');
            app.Clear.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.Clear.Text = 'CLEAR';
            app.STOPButton = uibutton(app.FORWARDPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ENDEFFECTORPanel = uipanel(app.UIFigure);
            app.ENDEFFECTORPanel.Title = 'END - EFFECTOR';
            app.PxEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PxEditFieldLabel.Text = 'Px';
            app.X_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.X_EE.ValueDisplayFormat = '%.2f';
            app.PyEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PyEditFieldLabel.Text = 'Py';
            app.Y_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Y_EE.ValueDisplayFormat = '%.2f';
            app.PzEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PzEditFieldLabel.Text = 'Pz';
            app.Z_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Z_EE.ValueDisplayFormat = '%.2f';
            app.PitchEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.Pitch_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Pitch_EE.ValueDisplayFormat = '%.2f';
            app.RollEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.Roll_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Roll_EE.ValueDisplayFormat = '%.2f';
            app.YawEditFieldLabel = uilabel(app.ENDEFFECTORPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.Yaw_EE = uieditfield(app.ENDEFFECTORPanel, 'numeric');
            app.Yaw_EE.ValueDisplayFormat = '%.2f';
            app.Coordinate_Checkbox = uicheckbox(app.ENDEFFECTORPanel);
            app.Coordinate_Checkbox.ValueChangedFcn = createCallbackFcn(app, @Coordinate_CheckboxValueChanged, true);
            app.Coordinate_Checkbox.Tag = 'Coordinateall';
            app.Coordinate_Checkbox.Text = 'Coordinate axes';
            app.WorkSpaceCheckBox = uicheckbox(app.ENDEFFECTORPanel);
            app.WorkSpaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @WorkSpaceCheckBoxValueChanged, true);
            app.WorkSpaceCheckBox.Text = 'WorkSpace';
            app.INVERSEPanel = uipanel(app.UIFigure);
            app.INVERSEPanel.Title = 'INVERSE';
            app.INVERSEButton = uibutton(app.INVERSEPanel, 'push');
            app.INVERSEButton.ButtonPushedFcn = createCallbackFcn(app, @INVERSEButtonPushed, true);
            app.INVERSEButton.Text = 'INVERSE';
            app.PxEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PxEditFieldLabel_2.Text = 'Px';
            app.PyEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PyEditFieldLabel_2.Text = 'Py';
            app.PzEditFieldLabel_2 = uilabel(app.INVERSEPanel);
            app.PzEditFieldLabel_2.Text = 'Pz';
            app.Px_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Px_Inv.ValueDisplayFormat = '%.2f';
            app.Px_Inv.ValueChangedFcn = createCallbackFcn(app, @Px_InvValueChanged, true);
            app.Py_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Py_Inv.ValueDisplayFormat = '%.2f';
            app.Py_Inv.ValueChangedFcn = createCallbackFcn(app, @Py_InvValueChanged, true);
            app.Pz_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Pz_Inv.ValueDisplayFormat = '%.2f';
            app.Pz_Inv.ValueChangedFcn = createCallbackFcn(app, @Pz_InvValueChanged, true);
            app.PLANNINGButton = uibutton(app.INVERSEPanel, 'push');
            app.PLANNINGButton.ButtonPushedFcn = createCallbackFcn(app, @PLANNINGButtonPushed, true);
            app.PLANNINGButton.Text = 'PLANNING';
            app.Pitch_Inv = uieditfield(app.INVERSEPanel, 'numeric');
            app.Pitch_Inv.ValueDisplayFormat = '%.2f';
            app.Pitch_Inv.ValueChangedFcn = createCallbackFcn(app, @Pitch_InvValueChanged, true);
            app.PzEditFieldLabel_3 = uilabel(app.INVERSEPanel);
            app.PzEditFieldLabel_3.Text = 'Pitch';
            app.PxEditFieldLabel_3 = uilabel(app.INVERSEPanel);
            app.PxEditFieldLabel_3.Text = 'a_max';
            app.PyEditFieldLabel_3 = uilabel(app.INVERSEPanel);
            app.PyEditFieldLabel_3.Text = 'v_max';
            app.amaxBox = uieditfield(app.INVERSEPanel, 'numeric');
            app.amaxBox.ValueDisplayFormat = '%.2f';
            app.vmaxBox = uieditfield(app.INVERSEPanel, 'numeric');
            app.vmaxBox.ValueDisplayFormat = '%.2f';
            app.ClearGraphsButton = uibutton(app.UIFigure, 'push');
            app.ClearGraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphsButtonPushed, true);
            app.ClearGraphsButton.Text = 'CLEAR GRAPHS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end